classdef PartC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MOVEPLATEButton           matlab.ui.control.Button
        MOVELabel                 matlab.ui.control.Label
        TOSpinner                 matlab.ui.control.Spinner
        TOSpinnerLabel            matlab.ui.control.Label
        FROMSpinner               matlab.ui.control.Spinner
        FROMSpinnerLabel          matlab.ui.control.Label
        GETSTATUSButton           matlab.ui.control.Button
        RESPONSESTextArea         matlab.ui.control.TextArea
        RESPONSESTextAreaLabel    matlab.ui.control.Label
        ImageExtendBars           matlab.ui.control.Image
        ImagePlateOnly            matlab.ui.control.Image
        GRIPPERLabel              matlab.ui.control.Label
        CLOSEButton               matlab.ui.control.Button
        OPENButton                matlab.ui.control.Button
        ZAXISLabel                matlab.ui.control.Label
        EXTENDButton              matlab.ui.control.Button
        RETRACTButton             matlab.ui.control.Button
        RESETButton               matlab.ui.control.Button
        XAXISLabel                matlab.ui.control.Label
        Button_4                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_1                  matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        CONNECTIONLabel           matlab.ui.control.Label
        PortNumberEditField       matlab.ui.control.EditField
        PortNumberEditFieldLabel  matlab.ui.control.Label
        ResettoDefaultButton      matlab.ui.control.Button
        ConfirmChangesButton      matlab.ui.control.Button
        SETTIMEDELAYSLabel        matlab.ui.control.Label
        TimeDelayColumnsLabel     matlab.ui.control.Label
        TimeDelayRowsLabel        matlab.ui.control.Label
        TimeDelayTable            matlab.ui.control.Table
        ImageGripper              matlab.ui.control.Image
        ImageRobotBackground      matlab.ui.control.Image
    end

    
    properties (Access = private)
        robot
        GripperXPosition %1=186, 2=289, 3=393, 4=494, 5=595
        PlateXPosition
        GripperOpen
        PlateGrabbed
        ZAxisExtended
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot=PlateLoaderSim(4);
            myTime=[0 60 20 30 0
            0 0 30 30 0
            0 30 0 30 0
            0 30 30 0 0
            0 30 20 60 0];
            app.TimeDelayTable.Data=myTime;
            app.ImageGripper.Position=[393,481,100,100];
            app.GripperXPosition=393;
            app.PlateXPosition=393;
            app.ImagePlateOnly.Position=[app.PlateXPosition,315,100,100];
            app.GripperOpen=0;
            app.PlateGrabbed=0;
            app.ZAxisExtended=0;
            app.ImageExtendBars.Visible="off";
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
           app.robot=PlateLoader(app.PortNumberEditField.Value-48);
           myTime=[0 60 20 30 0
               0 0 30 30 0
               0 30 0 30 0
               0 30 30 0 0
               0 30 20 60 0];
           app.TimeDelayTable.Data=myTime;
           app.ImageGripper.Position=[393,481,100,100];
           app.GripperXPosition=393;
           app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
           app.GripperOpen=0;
           app.PlateGrabbed=0;
           app.ZAxisExtended=0;
           app.ImageExtendBars.Visible="off";
           app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.robot=PlateLoaderSim(4);
            myTime=[0 60 20 30 0
                0 0 30 30 0
                0 30 0 30 0
                0 30 30 0 0
                0 30 20 60 0];
            app.TimeDelayTable.Data=myTime;
            app.ImageGripper.Position=[393,481,100,100];
            app.GripperXPosition=393;
            app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
            app.GripperOpen=0;
            app.PlateGrabbed=0;
            app.ZAxisExtended=0;
            app.ImageExtendBars.Visible="off";
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: ResettoDefaultButton
        function ResettoDefaultButtonPushed(app, event)
            app.robot.resetDefaultTimes
            app.TimeDelayTable.Data=[0 60 20 30 0
                0 0 30 30 0
                0 30 0 30 0
                0 30 30 0 0
                0 30 20 60 0];
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: ConfirmChangesButton
        function ConfirmChangesButtonPushed(app, event)
            app.robot.setTimeValues(app.TimeDelayTable.Data);
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.ImageGripper.Position=[186,481,100,100];
            app.robot.x(1)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=186;
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ImageGripper.Position=[289,481,100,100];
            app.robot.x(2)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=289;
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ImageGripper.Position=[393,481,100,100];
            app.robot.x(3)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=393;
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ImageGripper.Position=[494,481,100,100];
            app.robot.x(4)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=494;
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ImageGripper.Position=[595,481,100,100];
            app.robot.x(5)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=595;
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.robot.reset
            app.ImageGripper.Position=[393,481,100,100];
            app.GripperXPosition=393;
            app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
            app.GripperOpen=0;
            app.PlateGrabbed=0;
            app.ZAxisExtended=0;
            app.ImageExtendBars.Visible="off";
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: EXTENDButton
        function EXTENDButtonPushed(app, event)
            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.robot.extend
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: RETRACTButton
        function RETRACTButtonPushed(app, event)
            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            if app.PlateGrabbed == 0
                app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
                app.robot.open
                app.GripperOpen=1;
            elseif app.PlateGrabbed == 1 && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
                app.ImagePlateOnly.Position=[app.GripperXPosition,315,100,100];
                app.PlateXPosition=app.GripperXPosition;
                app.ImagePlateOnly.Visible="on";
                app.robot.open
                app.PlateGrabbed=0;
                app.GripperOpen=1;
            end
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            if app.GripperXPosition == app.PlateXPosition && app.ZAxisExtended ==1
                %FIGURE OUT HOW TO TELL THE RESPONSE THAT I HAVE GRABBED A
                %PLATE
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImagePlateOnly.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
            end
            if app.PlateGrabbed == 0 
                app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
                app.robot.close
                app.GripperOpen=0;
            end
             app.RESPONSESTextArea.Value=app.robot.getStatus;
        end

        % Button pushed function: GETSTATUSButton
        function GETSTATUSButtonPushed(app, event)
            %FIGURE OUT WHAT GET STATUS IS SUPPOSED TO DO
            [app.GripperXPosition,app.ZAxisExtended,app.GripperOpen,app.PlateGrabbed] = app.robot.getProperties;
            app.RESPONSESTextArea.Value=app.robot.getProperties;
        end

        % Button pushed function: MOVEPLATEButton
        function MOVEPLATEButtonPushed(app, event)
            if app.FROMSpinner.Value==1
                app.GripperXPosition=186;
            elseif app.FROMSpinner.Value==2
                app.GripperXPosition=289;
            elseif app.FROMSpinner.Value==3
                app.GripperXPosition=393;
            elseif app.FROMSpinner.Value==4
                app.GripperXPosition=494;
            elseif app.FROMSpinner.Value==5
                app.GripperXPosition595289;
            end
            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            pause(1)
            
            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.GripperOpen=1;

            if app.GripperXPosition == app.PlateXPosition && app.ZAxisExtended ==1
                %FIGURE OUT HOW TO TELL THE RESPONSE THAT I HAVE GRABBED A
                %PLATE
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImagePlateOnly.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
            end
            if app.PlateGrabbed == 0 
                app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
                app.robot.close
                app.GripperOpen=0;
            end
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.ZAxisExtended=0;
            pause(1)

            if app.TOSpinner.Value==1
                app.GripperXPosition=186;
            elseif app.TOSpinner.Value==2
                app.GripperXPosition=289;
            elseif app.TOSpinner.Value==3
                app.GripperXPosition=393;
            elseif app.TOSpinner.Value==4
                app.GripperXPosition=494;
            elseif app.TOSpinner.Value==5
                app.GripperXPosition595289;
            end
            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImagePlateOnly.Position=[app.GripperXPosition,315,100,100];
            app.PlateXPosition=app.GripperXPosition;
            app.ImagePlateOnly.Visible="on";
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
            app.GripperOpen=0;
            pause(1)

            app.ImageGripper.Position=[393,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=393;
            pause(1)

            app.robot.movePlate(app.FROMSpinner.Value,app.TOSpinner.Value)
            app.RESPONSESTextArea.Value=app.robot.getStatus;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageRobotBackground = uiimage(app.UIFigure);
            app.ImageRobotBackground.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.ImageGripper = uiimage(app.UIFigure);
            app.ImageGripper.ImageSource = fullfile(pathToMLAPP, 'gripper_closed_no_plate.jpg');
            app.TimeDelayTable = uitable(app.UIFigure);
            app.TimeDelayTable.ColumnName = '';
            app.TimeDelayTable.RowName = {};
            app.TimeDelayTable.ColumnEditable = true;
            app.TimeDelayRowsLabel = uilabel(app.UIFigure);
            app.TimeDelayRowsLabel.Text = {'1'; '2'; '3'; '4'; '5'};
            app.TimeDelayColumnsLabel = uilabel(app.UIFigure);
            app.TimeDelayColumnsLabel.Text = '  1    2    3    4    5';
            app.SETTIMEDELAYSLabel = uilabel(app.UIFigure);
            app.SETTIMEDELAYSLabel.Text = 'SET TIME DELAYS';
            app.ConfirmChangesButton = uibutton(app.UIFigure, 'push');
            app.ConfirmChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmChangesButtonPushed, true);
            app.ConfirmChangesButton.Text = 'Confirm Changes';
            app.ResettoDefaultButton = uibutton(app.UIFigure, 'push');
            app.ResettoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultButtonPushed, true);
            app.ResettoDefaultButton.Text = 'Reset to Default';
            app.PortNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditField = uieditfield(app.UIFigure, 'text');
            app.CONNECTIONLabel = uilabel(app.UIFigure);
            app.CONNECTIONLabel.Text = 'CONNECTION';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.XAXISLabel = uilabel(app.UIFigure);
            app.XAXISLabel.Text = 'X-AXIS';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.RETRACTButton = uibutton(app.UIFigure, 'push');
            app.RETRACTButton.ButtonPushedFcn = createCallbackFcn(app, @RETRACTButtonPushed, true);
            app.RETRACTButton.Text = 'RETRACT';
            app.EXTENDButton = uibutton(app.UIFigure, 'push');
            app.EXTENDButton.ButtonPushedFcn = createCallbackFcn(app, @EXTENDButtonPushed, true);
            app.EXTENDButton.Text = 'EXTEND';
            app.ZAXISLabel = uilabel(app.UIFigure);
            app.ZAXISLabel.Text = 'Z-AXIS';
            app.OPENButton = uibutton(app.UIFigure, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.GRIPPERLabel = uilabel(app.UIFigure);
            app.GRIPPERLabel.Text = 'GRIPPER';
            app.ImagePlateOnly = uiimage(app.UIFigure);
            app.ImagePlateOnly.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ImageExtendBars = uiimage(app.UIFigure);
            app.ImageExtendBars.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.RESPONSESTextAreaLabel = uilabel(app.UIFigure);
            app.RESPONSESTextAreaLabel.Text = 'RESPONSES';
            app.RESPONSESTextArea = uitextarea(app.UIFigure);
            app.GETSTATUSButton = uibutton(app.UIFigure, 'push');
            app.GETSTATUSButton.ButtonPushedFcn = createCallbackFcn(app, @GETSTATUSButtonPushed, true);
            app.GETSTATUSButton.Text = {'GET '; 'STATUS'};
            app.FROMSpinnerLabel = uilabel(app.UIFigure);
            app.FROMSpinnerLabel.Text = 'FROM:';
            app.FROMSpinner = uispinner(app.UIFigure);
            app.FROMSpinner.Limits = [1 5];
            app.FROMSpinner.Value = 1;
            app.TOSpinnerLabel = uilabel(app.UIFigure);
            app.TOSpinnerLabel.Text = 'TO:';
            app.TOSpinner = uispinner(app.UIFigure);
            app.TOSpinner.Limits = [1 5];
            app.TOSpinner.Value = 1;
            app.MOVELabel = uilabel(app.UIFigure);
            app.MOVELabel.Text = 'MOVE';
            app.MOVEPLATEButton = uibutton(app.UIFigure, 'push');
            app.MOVEPLATEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEPLATEButtonPushed, true);
            app.MOVEPLATEButton.Text = 'MOVE PLATE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PartC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end